classdef DOT1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        SaveAsMenu                  matlab.ui.container.Menu
        Menu                        matlab.ui.container.Menu
        QuantityEditFieldLabel      matlab.ui.control.Label
        QuantityEditField           matlab.ui.control.NumericEditField
        NumberofbedsEditFieldLabel  matlab.ui.control.Label
        NumberofbedsEditField       matlab.ui.control.NumericEditField
        AddButton                   matlab.ui.control.Button
        GenerateButton              matlab.ui.control.Button
        RoomTypeDropDownLabel       matlab.ui.control.Label
        RoomTypeDropDown            matlab.ui.control.DropDown
        RefreshButton               matlab.ui.control.Button
        ProjectTitleEditFieldLabel  matlab.ui.control.Label
        ProjectTitleEditField       matlab.ui.control.EditField
        RoomsTextAreaLabel          matlab.ui.control.Label
        RoomsTextArea               matlab.ui.control.TextArea
        ErrorPromptsTextAreaLabel   matlab.ui.control.Label
        ErrorPromptsTextArea        matlab.ui.control.TextArea
        ExitButton                  matlab.ui.control.Button
        OpenButton                  matlab.ui.control.Button
        AGSDropDownLabel            matlab.ui.control.Label
        AGSDropDown                 matlab.ui.control.DropDown
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
Q = app.QuantityEditField.Value;
B = app.NumberofbedsEditField.Value;
Y = app.RoomTypeDropDown.Value;

G = Index.i;
ii = DOT_data.index;
value = app.ProjectTitleEditField.Value;
Jit = app.AGSDropDown.Value;
if isempty(G.Display)
    G.Display = 0;
end
if isempty(ii.index_prime)
    ii.index_prime = 0;
end
if  contains (Y, 'Anaesthetic room')
    DOT.anaesthetic_room(Q,B,Y,Jit);
    G.Display = G.Display+1;
    app.RoomsTextArea.Value{G.Display,1} = Y;
elseif contains(Y,'Anaesthetist (Adult acute day care accommodation)')
    DOT.Anaesthetist_aadca(Q,B,Y,Jit);
    G.Display = G.Display+1;
    app.RoomsTextArea.Value{G.Display,1} = Y;
elseif contains(Y, 'Angiography')
    DOT.angiography(Q,B,Y,Jit);
     G.Display = G.Display+1;
    app.RoomsTextArea.Value{G.Display,1} = Y;
elseif contains(Y, 'Burns unit')
    DOT.burns_unit(Q,B,Y,Jit);
     G.Display = G.Display+1;
    app.RoomsTextArea.Value{G.Display,1} = Y;
elseif contains(Y,'CAT')
    DOT.CAT(Q,B,Y,Jit);
    G.Display = G.Display+1;
    app.RoomsTextArea.Value{G.Display,1} = Y;
elseif contains(Y, 'coronary care unit')
    DOT.ccu(Q,B,Y,Jit);
    G.Display = G.Display+1;
    app.RoomsTextArea.Value{G.Display,1} = Y;
elseif contains(Y, 'critical care area')
    DOT.critical_care_area(Q,B,Y,Jit);
    G.Display = G.Display+1;
    app.RoomsTextArea.Value{G.Display,1} = Y;
elseif contains(Y, 'Day care')
    DOT.day_care(Q,B,Y,Jit);
    G.Display = G.Display+1;
    app.RoomsTextArea.Value{G.Display,1} = Y;
elseif contains(Y, 'ECT')
    DOT.ECT(Q,B,Y,Jit);
    G.Display = G.Display+1;
    app.RoomsTextArea.Value{G.Display,1} = Y;
elseif contains(Y, 'Endoscopy')
    DOT.endoscopy(Q,B,Y,Jit);
    G.Display = G.Display+1;
    app.RoomsTextArea.Value{G.Display,1} = Y;
elseif contains(Y, 'Equipment Room')
    DOT.Equipment_room(Q,B,Y,Jit);
    G.Display = G.Display+1;
    app.RoomsTextArea.Value{G.Display,1} = Y;
elseif contains(Y, 'Equipment room NICU')
    DOT.Equipment_room_NICU(Q,B,Y,Jit);
    G.Display = G.Display+1;
    app.RoomsTextArea.Value{G.Display,1} = Y;
elseif contains (Y,'Fluoroscopy')
    DOT.fluoroscopy(Q,B,Y,Jit);
    G.Display = G.Display+1;
    app.RoomsTextArea.Value{G.Display,1} = Y;
elseif contains (Y, 'General_Purpose Room')
    DOT.general_purpose(Q,B,Y,Jit);
    G.Display = G.Display+1;
    app.RoomsTextArea.Value{G.Display,1} = Y;
elseif contains(Y, 'High Dependency Unit')
    DOT.HDU(Q,B,Y,Jit);
    G.Display = G.Display+1;
    app.RoomsTextArea.Value{G.Display,1} = Y;
elseif contains(Y, 'Holding and Recovery')
    DOT.Holding_and_Recovery(Q,B,Y,Jit);
    G.Display = G.Display+1;
    app.RoomsTextArea.Value{G.Display,1} = Y;
elseif contains(Y, 'IAP Room')
    DOT.IAP_room(Q,B,Y);
    G.Display = G.Display+1;
    app.RoomsTextArea.Value{G.Display,1} = Y;
elseif contains(Y, 'Intensive Care Unit')
    DOT.icu(Q,B,Y,Jit);
    G.Display = G.Display+1;
    app.RoomsTextArea.Value{G.Display,1} = Y;
elseif contains(Y, 'Inpatient Room')
    DOT.Inpatient_room(Q,B,Y);
    G.Display = G.Display+1;
    app.RoomsTextArea.Value{G.Display,1} = Y;
elseif contains(Y, 'LDR Room')
    DOT.ldr(Q,B,Y,Jit);
    G.Display = G.Display+1;
    app.RoomsTextArea.Value{G.Display,1} = Y;
elseif contains(Y, 'Lineac Bunker')
    DOT.lineac_bunker(Q,B,Y,Jit);
    G.Display = G.Display+1;
    app.RoomsTextArea.Value{G.Display,1} = Y;
elseif contains(Y,'MRI') 
    DOT.MRI(Q,B,Y,Jit);
    G.Display = G.Display+1;
    app.RoomsTextArea.Value{G.Display,1} = Y;
elseif contains(Y, 'NICU') && ~contains(Y, 'Equipment_room_NICU')
    DOT.NICU(Q,B,Y);
    G.Display = G.Display+1;
    app.RoomsTextArea.Value{G.Display,1} = Y;
elseif contains(Y, 'Nursery')
    DOT.nursery(Q,B,Y);
    G.Display = G.Display+1;
    app.RoomsTextArea.Value{G.Display,1} = Y;
elseif contains(Y, 'Operating Theatre_general surgery')
    DOT.OT_gs(Q,B,Y,Jit)
    G.Display = G.Display+1;
    app.RoomsTextArea.Value{G.Display,1} = Y;
elseif contains (Y, 'Operating Theatre_neurosurgery')
    DOT.OT_neurosurgery(Q,B,Y,Jit);
    G.Display = G.Display+1;
    app.RoomsTextArea.Value{G.Display,1} = Y;
elseif contains(Y, 'Operating Theatre_orthopaedics')
    DOT.OT_orthopaedic(Q,B,Y,Jit);
    G.Display = G.Display+1;
    app.RoomsTextArea.Value{G.Display,1} = Y;
elseif contains (Y, 'Post-anaesthesia recovery_emergency')
    DOT.par_emergency(Q,B,Y,Jit);
    G.Display = G.Display+1;
    app.RoomsTextArea.Value{G.Display,1} = Y;
elseif contains(Y, 'Post-anaesthesia recovery_ldr')
    DOT.par_ldr(Q,B,Y,Jit);
    G.Display = G.Display+1;
    app.RoomsTextArea.Value{G.Display,1} = Y;
elseif contains(Y, 'Post-anaesthesia recovery_adult mental illness accommodation')
    DOT.par_mental(Q,B,Y);
    G.Display = G.Display+1;
    app.RoomsTextArea.Value{G.Display,1} = Y;
elseif contains (Y,'Post-anaesthesia recovery_operation theatre')
    DOT.par_ot(Q,B,Y);
    G.Display = G.Display+1;
    app.RoomsTextArea.Value{G.Display,1} = Y;
elseif contains(Y, 'Plaster Room')
    DOT.Plaster_room(Q,B,Y,Jit);
    G.Display = G.Display+1;
    app.RoomsTextArea.Value{G.Display,1} = Y;
elseif contains (Y, 'Renal')
    DOT.renal(Q,B,Y);
    G.Display = G.Display+1;
    app.RoomsTextArea.Value{G.Display,1} = Y;
elseif contains (Y, 'Resuscitation room')
    W = DOT.Resuscitation_Room(Q,B,Y,Jit);
    G.Display = G.Display+1;
    app.RoomsTextArea.Value{G.Display,1} = Y;
elseif contains(Y, 'Special Procedures Room')
    DOT.special_procedures(Q,B,Y,Jit)
    G.Display = G.Display+1;
    app.RoomsTextArea.Value{G.Display,1} = Y;
elseif contains (Y, 'Surgeon_adult acute day care accommodation')
    DOT.Surgeon_aadca(Q,B,Y);
    G.Display = G.Display+1;
    app.RoomsTextArea.Value{G.Display,1} = Y;
elseif contains (Y, 'Tomography')
    DOT.tomography(Q,B,Y,Jit);
    G.Display = G.Display+1;
    app.RoomsTextArea.Value{G.Display,1} = Y;
elseif contains(Y, 'Treatment Room')
    DOT.treatment_room(Q,B,Y);
    G.Display = G.Display+1;
    app.RoomsTextArea.Value{G.Display,1} = Y;
elseif contains(Y, 'Ultrasound')
    DOT.ultrasound(Q,B,Y,Jit)
    G.Display = G.Display+1;
    app.RoomsTextArea.Value{G.Display,1} = Y;
elseif contains(Y, 'Urography')
    DOT.urography(Q,B,Y)
    G.Display = G.Display+1;
    app.RoomsTextArea.Value{G.Display,1} = Y;
elseif contains (Y, 'Wash Room')
    DOT.Wash_room(Q,B,Y)
    G.Display = G.Display+1;
    app.RoomsTextArea.Value{G.Display,1} = Y;
elseif contains (Y, 'X-Ray Room')
    DOT.x_ray_room(Q,B,Y,Jit)
    G.Display = G.Display+1;
    app.RoomsTextArea.Value{G.Display,1} = Y;
else
app.ErrorPromptsTextArea.Value = 'No input added';
end
Floor_name(value);


        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
value = app.ProjectTitleEditField.Value;
if isfile('modular-DOT-Table.xlsx') == 1
    Floor_name(value);
    column_resize;
    condense;
    winopen('modular-DOT-Table.xlsx');
end
        end

        % Value changed function: RoomTypeDropDown
        function RoomTypeDropDownValueChanged(app, event)
            
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            clear DOT_data;
            clear Index;
            clear app.RoomsTextArea.Value;
            app.RoomsTextArea.Value = '';            
            if isfile('~$modular-DOT-Table.xlsx') == 1
                app.ErrorPromptsTextArea.Value = 'Close the file to be overwritten';
            else
                delete 'modular-DOT-Table.xlsx';
                app.ErrorPromptsTextArea.Value = '';
            end
            
        end

        % Value changed function: ProjectTitleEditField
        function ProjectTitleEditFieldValueChanged(app, event)
            
        end

        % Menu selected function: SaveAsMenu
        function SaveAsMenuSelected(app, event)
         Y = SaveAs(app.ProjectTitleEditField.Value);
        app.ErrorPromptsTextArea.Value = Y;
        
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            clear DOT_data;
            clear Index;
            clear error_cache;
            clear G;
            delete('modular-DOT-Table.xlsx')
            close(app.UIFigure)
            
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            winopen('modular-DOT-Table.xlsx');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAsMenuSelected, true);
            app.SaveAsMenu.Text = 'SaveAs';
            app.Menu = uimenu(app.UIFigure);
            app.QuantityEditFieldLabel = uilabel(app.UIFigure);
            app.QuantityEditFieldLabel.Text = 'Quantity';
            app.QuantityEditField = uieditfield(app.UIFigure, 'numeric');
            app.QuantityEditField.Value = 1;
            app.NumberofbedsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofbedsEditFieldLabel.Text = 'Number of beds';
            app.NumberofbedsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofbedsEditField.Value = 1;
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate!';
            app.RoomTypeDropDownLabel = uilabel(app.UIFigure);
            app.RoomTypeDropDownLabel.Text = 'Room Type';
            app.RoomTypeDropDown = uidropdown(app.UIFigure);
            app.RoomTypeDropDown.Items = {'(select)', 'Anaesthetic room', 'Anaesthetist (Adult acute day care accommodation)', 'Angiography', 'Burns unit', 'CAT', 'coronary care unit', 'critical care area', 'Day care', 'ECT', 'Endoscopy', 'Equipment Room', 'Equipment room_NICU', 'Fluoroscopy', 'General_Purpose Room', 'High Dependency Unit', 'Holding and Recovery', 'IAP Room', 'Intensive Care Unit', 'Inpatient Room', 'LDR Room', 'Lineac Bunker', 'MRI', ' NICU', 'Nursery', 'Operating Theatre_general surgery', 'Operating Theatre_neurosurgery', 'Operating Theatre_orthopaedics', 'Post-anaesthesia recovery_emergency', 'Post-anaesthesia recovery_ldr', 'Post-anaesthesia recovery_adult mental illness accommodation', 'Post-anaesthesia recovery_operation theatre', 'Plaster Room', 'Renal', 'Resuscitation room', 'Special Procedures Room', 'Surgeon_adult acute day care accommodation', 'Tomography', 'Treatment Room', 'Ultrasound', 'Urography', 'Wash Room', 'X-Ray Room', '', '', '', '', '', '', '', '', '', '', '', '', '', '', ''};
            app.RoomTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @RoomTypeDropDownValueChanged, true);
            app.RoomTypeDropDown.Value = '(select)';
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh!';
            app.ProjectTitleEditFieldLabel = uilabel(app.UIFigure);
            app.ProjectTitleEditFieldLabel.Text = 'Project Title';
            app.ProjectTitleEditField = uieditfield(app.UIFigure, 'text');
            app.ProjectTitleEditField.ValueChangedFcn = createCallbackFcn(app, @ProjectTitleEditFieldValueChanged, true);
            app.RoomsTextAreaLabel = uilabel(app.UIFigure);
            app.RoomsTextAreaLabel.Text = 'Rooms ';
            app.RoomsTextArea = uitextarea(app.UIFigure);
            app.ErrorPromptsTextAreaLabel = uilabel(app.UIFigure);
            app.ErrorPromptsTextAreaLabel.Text = 'Error Prompts';
            app.ErrorPromptsTextArea = uitextarea(app.UIFigure);
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.AGSDropDownLabel = uilabel(app.UIFigure);
            app.AGSDropDownLabel.Text = 'AGS';
            app.AGSDropDown = uidropdown(app.UIFigure);
            app.AGSDropDown.Items = {'(select)', 'AGS Venturi', 'AGSS', ''};
            app.AGSDropDown.Value = '(select)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DOT1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end